classdef opensim_batch_uq_v0_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SettingsMenu                    matlab.ui.container.Menu
        LoadSettingsMenu                matlab.ui.container.Menu
        SaveSettingsMenu                matlab.ui.container.Menu
        ClearAllMenu                    matlab.ui.container.Menu
        CustomMFileButton               matlab.ui.control.Button
        CustomimportEditField           matlab.ui.control.EditField
        CustomimportEditFieldLabel      matlab.ui.control.Label
        BodyAnalysisCheckBox            matlab.ui.control.CheckBox
        Level3DropDown                  matlab.ui.control.DropDown
        Level3DropDownLabel             matlab.ui.control.Label
        StringOutputEditField           matlab.ui.control.EditField
        StringOutputEditFieldLabel      matlab.ui.control.Label
        ScalarOutputEditField           matlab.ui.control.NumericEditField
        ScalarOutputEditFieldLabel      matlab.ui.control.Label
        Level2DropDown                  matlab.ui.control.DropDown
        Level2DropDownLabel             matlab.ui.control.Label
        Level1DropDown                  matlab.ui.control.DropDown
        Level1DropDownLabel             matlab.ui.control.Label
        TrialDropDown                   matlab.ui.control.DropDown
        TrialDropDownLabel              matlab.ui.control.Label
        AnalysisDropDown                matlab.ui.control.DropDown
        AnalysisDropDownLabel           matlab.ui.control.Label
        HoldonCheckBox                  matlab.ui.control.CheckBox
        ProcessingProgressTextArea      matlab.ui.control.TextArea
        ProcessingProgressTextAreaLabel  matlab.ui.control.Label
        ExternalLoadsXMLEditField       matlab.ui.control.EditField
        ExternalLoadsXMLEditFieldLabel  matlab.ui.control.Label
        IKXMLEditField                  matlab.ui.control.EditField
        IKXMLEditFieldLabel             matlab.ui.control.Label
        ScaleXMLEditField               matlab.ui.control.EditField
        ScaleXMLEditFieldLabel          matlab.ui.control.Label
        RunBatchProcessToolsButton      matlab.ui.control.Button
        DataimportandconvertCheckBox    matlab.ui.control.CheckBox
        IDLoadsFileButton               matlab.ui.control.Button
        IKFileButton                    matlab.ui.control.Button
        ScaleFileButton                 matlab.ui.control.Button
        MuscleAnalysisCheckBox          matlab.ui.control.CheckBox
        InverseDynamicsCheckBox         matlab.ui.control.CheckBox
        InverseKinematicsCheckBox       matlab.ui.control.CheckBox
        ScaleCheckBox                   matlab.ui.control.CheckBox
        DynamicFolderEditField          matlab.ui.control.EditField
        DynamicFolderEditFieldLabel     matlab.ui.control.Label
        ModelFileEditField              matlab.ui.control.EditField
        ModelFileEditFieldLabel         matlab.ui.control.Label
        StaticC3DEditField              matlab.ui.control.EditField
        StaticC3DEditFieldLabel         matlab.ui.control.Label
        DynamicC3DlistListBox           matlab.ui.control.ListBox
        DynamicC3DlistListBoxLabel      matlab.ui.control.Label
        DynamicFilesButton              matlab.ui.control.Button
        StaticFileButton                matlab.ui.control.Button
        ModelFileButton                 matlab.ui.control.Button
        ParticipantandFilteringParametersPanel  matlab.ui.container.Panel
        MassScalerEditField             matlab.ui.control.NumericEditField
        MassScaler01EditFieldLabel      matlab.ui.control.Label
        ParticipantMasskgEditField      matlab.ui.control.NumericEditField
        ParticipantMasskgEditFieldLabel  matlab.ui.control.Label
        ParticipantCodeEditField        matlab.ui.control.EditField
        ParticipantCodeEditFieldLabel   matlab.ui.control.Label
        ForceLowPassFreqHzSpinner       matlab.ui.control.Spinner
        ForceLowPassFreqHzSpinnerLabel  matlab.ui.control.Label
        KinematicsLowPassFreqHzSpinner  matlab.ui.control.Spinner
        KinematicsLowPassFreqHzSpinnerLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        info_structure % structure containing important file information and parameters
        data_structure % the structure containing the data for each file
        scale_structure % the structure containing key scaling outputs
        ik_structure % the structure containing the IK outputs
        id_structure % the structure containing the ID outputs
        ma_structure % the structure containing the MA outputs
        out_text = ''; % the text output for processing
        filelist = [];
        all_data % structure to contain all other structures
    end
    
    methods (Access = private)
        
        % this function will load the c3d data and write the TRC and
        % GRF.MOT files
        function run_dataimport(app)
            
            % first load the static data and create the TRC and MOT (GRF) files
            [~,name,~] = fileparts(app.info_structure.staticC3d);
            app.data_structure.(name) = btk_loadc3d_app(app.info_structure.staticC3d);
            if isempty(app.CustomimportEditField.Value)
                [app.data_structure.(name)] = btk_c3d2trc_app(app.data_structure.(name));
            else
                [mfilepath,evalfunc,~] = fileparts(app.CustomimportEditField.Value);
                path(path, mfilepath)
                [app.data_structure.(name)] = eval([evalfunc '(app.data_structure.(name));']);
            end            
            
            % next load the dynamic data files and create the TRC and MOT (GRF) files
            for j = 1 : length(app.info_structure.dynamic_C3dfiles)
                [~,name,~] = fileparts(app.info_structure.dynamic_C3dfiles{j});
                app.data_structure.(name) = btk_loadc3d_app([app.info_structure.dynamic_C3dpath app.info_structure.dynamic_C3dfiles{j}]);
                
                if isempty(app.CustomimportEditField.Value)
                    [app.data_structure.(name)] = btk_c3d2trc_app(app.data_structure.(name));
                else
                    [app.data_structure.(name)] = eval([evalfunc '(app.data_structure.(name));']);
                end                
                
                app.filelist{j} = name;
            end
            
        end
        
        % this function will setup and run the scaling tool based on the static data
        % file
        function run_scale_app(app)
            if isempty(app.ScaleXMLEditField.Value)
                [fname, pathname] = uigetfile({'*.xml','xml file'}, 'Select generic scale setup file');
                xmlfname = [pathname fname];
                app.ScaleXMLEditField.Value = xmlfname;
                app.ScaleCheckBox.Value = 1;
            end
            scale_tool_app(app)
        end
        
        % this is the tool that runs the scale app
        function scale_tool_app(app)
            app.out_text = 'Scale Tool Processing....';
            app.ProcessingProgressTextArea.Value = app.out_text;
            import org.opensim.modeling.*
            % get the name of the static file
            [~,name,~] = fileparts(app.info_structure.staticC3d);
            [~,mod_name,~] = fileparts(app.ModelFileEditField.Value);
            %change directory to the model directory to ensure it works
            
            %load scale tool and associated tools
            ScTool = ScaleTool(app.ScaleXMLEditField.Value);
            ScTool.setName(app.info_structure.ParticipantCode) 
            ScTool.setPathToSubject('');
            GMM = ScTool.getGenericModelMaker;
            MS = ScTool.getModelScaler;
            MP = ScTool.getMarkerPlacer;
            
            %add model to generic setup file            
            GMM.setModelFileName(app.ModelFileEditField.Value);            
            GMM.setName(mod_name);
            
            %add output files
            MS.setOutputScaleFileName([app.info_structure.dynamic_C3dpath 'ModelScaling\' app.info_structure.ParticipantCode '_scaleSetApplied' '.xml']);
            MS.setOutputModelFileName([app.info_structure.dynamic_C3dpath 'ModelScaling\' app.info_structure.ParticipantCode '_modelScaled' '.osim']);
            MP.setOutputMotionFileName([app.info_structure.dynamic_C3dpath 'ModelScaling\' app.info_structure.ParticipantCode '_static' '.mot']);
            MP.setOutputModelFileName([app.info_structure.dynamic_C3dpath 'ModelScaling\' app.info_structure.ParticipantCode '_modelScaled' '.osim']);
            MP.setOutputMarkerFileName([app.info_structure.dynamic_C3dpath 'ModelScaling\' app.info_structure.ParticipantCode '_markersScaled' '.xml']);
            app.info_structure.ScaledModel = [app.info_structure.dynamic_C3dpath 'ModelScaling\' app.info_structure.ParticipantCode '_modelScaled' '.osim'];
            
            % setup the specific file parameters
            % note that the name and path of the file is in the data_structure for static file
            MS.setMarkerFileName(app.data_structure.(name).TRC_Filename);  
            MP.setMarkerFileName(app.data_structure.(name).TRC_Filename);

            %add time range
            InitialTime = app.data_structure.(name).marker_data.Time(1);
            FinalTime = app.data_structure.(name).marker_data.Time(1)+0.2;            
            time_range = ArrayDouble;
            time_range.append(InitialTime);
            time_range.append(FinalTime);
            MS.setTimeRange(time_range);
            MP.setTimeRange(time_range);
            
            %add mass
            mass = app.info_structure.Mass;
            factor = app.info_structure.MassScaler;
            ScTool.setSubjectMass(mass * factor);
            
            %create the ModelScaling directory above c3d directory if it
            %doesn't exist
            if isempty(dir([app.info_structure.dynamic_C3dpath 'ModelScaling']))
                mkdir(app.info_structure.dynamic_C3dpath,'ModelScaling');
            end
            
            %write new .xml file in setup folder
            ScTool.print([app.info_structure.dynamic_C3dpath 'ModelScaling\' app.info_structure.ParticipantCode '_setupScale.xml']);
            
            % run scaling tool;
            ScTool.run();
            app.out_text = [app.out_text 'DONE.'];
            app.ProcessingProgressTextArea.Value = app.out_text;
            pause(2)
%             % use command line as workaround while the scaletool is not
%             % working via API
%             cd([app.info_structure.dynamic_C3dpath 'ModelScaling\']);
%             com = ['opensim-cmd run-tool ' [app.info_structure.ParticipantCode '_setupScale.xml']];
%             system(com);
%             cd(app.info_structure.dynamic_C3dpath)
        end
        
        % this function will run the scaling tool based on the static data
        % file
        function run_ik_app(app)
            app.out_text = {'IK Tool Processing....'};
            app.ProcessingProgressTextArea.Value = app.out_text;
            drawnow;
            %load the setting file template if it isn't already loaded
            if isempty(app.IKXMLEditField.Value)
                [fname, pathname] = uigetfile({'*.xml','xml file'}, 'Select generic IK setup file');
                xmlfname = [pathname fname];
                app.IKXMLEditField.Value = xmlfname;
                app.InverseKinematicsCheckBox.Value = 1;
            end
            %loop through all of the dynamic c3d files and run the ik tool
            %on each one
            for j = 1:length(app.info_structure.dynamic_C3dfiles)
                ik_tool_app(app,j)
            end
            
            app.out_text = [app.out_text;{'DONE'}];
            app.ProcessingProgressTextArea.Value = app.out_text;
            pause(2);
        end
        
        % this is the tool that runs the scale app
        function ik_tool_app(app,j)
            import org.opensim.modeling.*
            
            ikTool = InverseKinematicsTool(app.IKXMLEditField.Value);
            
            model = Model(app.info_structure.ScaledModel);
            
            % Tell Tool to use the loaded model
            ikTool.setModel(model);
            
            % define the file names          
            [~,fname,~] = fileparts(app.info_structure.dynamic_C3dfiles{j});
            marker_file = app.data_structure.(fname).TRC_Filename;
            mot_file = [app.info_structure.dynamic_C3dpath 'InverseKinematics\' fname '.mot'];
            app.data_structure.(fname).MOT_Filename = mot_file;
            setup_file = [app.info_structure.dynamic_C3dpath 'InverseKinematics\' fname '_iksetup.xml'];
            
            %create the InverseKinematics directory above c3d directory if it
            %doesn't exist
            if isempty(dir([app.info_structure.dynamic_C3dpath 'InverseKinematics']))
                mkdir(app.info_structure.dynamic_C3dpath,'InverseKinematics');
            end
            
            % Get trc data to determine time range
            markerData = MarkerData(marker_file);
            
            % Get initial and intial time
            initial_time = markerData.getStartFrameTime();
            final_time = markerData.getLastFrameTime();
            
            % Setup the ikTool for this trial
            ikTool.setMarkerDataFileName(marker_file);
            ikTool.setStartTime(initial_time);
            ikTool.setEndTime(final_time);
            ikTool.setOutputMotionFileName(mot_file);
            ikTool.setName(fname)
            ikTool.setResultsDir([app.info_structure.dynamic_C3dpath 'InverseKinematics\']);
            
            %write the XML setup file in same directory as MOT file
            ikTool.print(setup_file);
            
            app.out_text = [app.out_text;{['Processing ' fname '....']}];
            app.ProcessingProgressTextArea.Value = app.out_text;
            drawnow;
            
            % Run IK via API
            ikTool.run();
            
            % Load the results from the STO file and save to the IK
            % structure
            app.ik_structure.(fname) = load_sto_file(mot_file);
        end
        
        % this function will run the scaling tool based on the static data
        % file
        function run_id_app(app)
            app.out_text = {'ID Tool Processing....'};
            app.ProcessingProgressTextArea.Value = app.out_text;
            drawnow;
            %load the setting file template if it isn't already loaded
            if isempty(app.ExternalLoadsXMLEditField.Value)
                [fname, pathname] = uigetfile({'*.xml','xml file'}, 'Select generic external loads file');
                xmlfname = [pathname fname];
                app.ExternalLoadsXMLEditField.Value = xmlfname;
                app.InverseDynamicsCheckBox.Value = 1;
            end
            %loop through all of the dynamic c3d files and run the ik tool
            %on each one
            for j = 1:length(app.info_structure.dynamic_C3dfiles)
                id_tool_app(app,j)
            end
            
            app.out_text = [app.out_text;{'DONE'}];
            app.ProcessingProgressTextArea.Value = app.out_text;
            pause(2);
        end
        
        function id_tool_app(app,j)
            import org.opensim.modeling.*
            
            [~,name,~] = fileparts(app.info_structure.dynamic_C3dfiles{j});
            
            idTool = InverseDynamicsTool();
            idTool.setModelFileName(app.info_structure.ScaledModel);
            idTool.setCoordinatesFileName(app.data_structure.(name).MOT_Filename);
            idTool.setLowpassCutoffFrequency(app.KinematicsLowPassFreqHzSpinner.Value);
            
            % Get mot data to determine time range
            motData = Storage(app.data_structure.(name).MOT_Filename);
            
            % Get initial and intial time
            initial_time = motData.getFirstTime();
            final_time = motData.getLastTime();
            
            idTool.setStartTime(initial_time);
            idTool.setEndTime(final_time);
            
            %Set folders
            idTool.setResultsDir([app.info_structure.dynamic_C3dpath 'InverseDynamics\']);
            sto_filename = [app.info_structure.dynamic_C3dpath 'InverseDynamics\' name '.sto'];
            app.data_structure.(name).ID_Filename = sto_filename;
            idTool.setOutputGenForceFileName([name '.sto']);
            
            %create the InverseDynamics directory above c3d directory if it
            %doesn't exist
            if isempty(dir([app.info_structure.dynamic_C3dpath 'InverseDynamics']))
                mkdir(app.info_structure.dynamic_C3dpath,'InverseDynamics');
            end
            
            % Set forces_to_exclude
            excludedForces = ArrayStr();
            excludedForces.append('Muscles');
            idTool.setExcludedForces(excludedForces);
            
            % make the external loads file for this trial
            ExtLoads = ExternalLoads(app.ExternalLoadsXMLEditField.Value,1);
            ExtLoads.setDataFileName(app.data_structure.(name).GRF_Filename);
            
            % Create name for the external loads xml file and print this
            % out to XML file
            extLoadsXml = [app.info_structure.dynamic_C3dpath 'InverseDynamics\' name '_externalloads.xml'];
            ExtLoads.print(extLoadsXml);            
         
            % Define the new external loads file to use
            idTool.setExternalLoadsFileName(extLoadsXml);
            
            %Print ID setup file
            setupIDFile = [app.info_structure.dynamic_C3dpath 'InverseDynamics\' name '_setupID.xml'];
            idTool.print(setupIDFile);
            
            app.out_text = [app.out_text;{['Processing ' name '....']}];
            app.ProcessingProgressTextArea.Value = app.out_text;
            drawnow;
            
            idTool.run();
            
            % Load the results from the STO file and save to the IK
            % structure
            app.id_structure.(name) = load_sto_file(sto_filename);

        end
        
        % this function will run the scaling tool based on the static data
        % file
        function run_ma_app(app)
            app.out_text = {'Muscle Analysis Tool Processing....'};
            app.ProcessingProgressTextArea.Value = app.out_text;
            drawnow;

            %loop through all of the dynamic c3d files and run the ik tool
            %on each one
            for j = 1:length(app.info_structure.dynamic_C3dfiles)
                ma_tool_app(app,j)
            end
            
            app.out_text = [app.out_text;{'DONE'}];
            app.ProcessingProgressTextArea.Value = app.out_text;
            pause(2);
        end
        
        function ma_tool_app(app,j)
            import org.opensim.modeling.*
            [~,name,~] = fileparts(app.info_structure.dynamic_C3dfiles{j});
            
            %create the MuscleAnalysis directory above c3d directory if it
            %doesn't exist
            if isempty(dir([app.info_structure.dynamic_C3dpath 'MuscleAnalysis']))
                mkdir(app.info_structure.dynamic_C3dpath,'MuscleAnalysis');
            end
            
            M = Model(app.info_structure.ScaledModel);
            tool = AnalyzeTool();
            tool.setModel(M);
            Muscle_AnL = MuscleAnalysis(M);
            M.addAnalysis(Muscle_AnL);
            Muscle_AnL.setComputeMoments(false);
            
            tool.setCoordinatesFileName(app.data_structure.(name).MOT_Filename);
            tool.setLoadModelAndInput(true);
            tool.setSolveForEquilibrium(true);
            tool.setName(name);
            tool.setResultsDir([app.info_structure.dynamic_C3dpath 'MuscleAnalysis\' name '\']);
            motData = Storage(app.data_structure.(name).MOT_Filename);
            initial_time = motData.getFirstTime();
            final_time = motData.getLastTime();
            tool.setStartTime(initial_time);
            tool.setFinalTime(final_time);
            
            app.out_text = [app.out_text;{['Processing ' name '....']}];
            app.ProcessingProgressTextArea.Value = app.out_text;
            drawnow;
            
            tool.run();
            
            % Load the results from the STO file and save to the IK
            % structure
            f = dir([app.info_structure.dynamic_C3dpath 'MuscleAnalysis\' name '\*.sto']);
            for i = 1:length(f)
                analysis_name = strrep(strrep(strrep(f(i).name,'_MuscleAnalysis_',''),name,''),'.sto','');
                app.ma_structure.(name).(analysis_name) = load_sto_file([f(i).folder '\' f(i).name]);
            end
        end
        
        % this function will change the drop downs when a different
        % analysis is selected.
        function set_analysis_dropdown(app)
            switch app.AnalysisDropDown.Value
                case 'Data'
                    fnames1 = fieldnames(app.data_structure);
                    app.TrialDropDown.Items = fnames1;
                    fnames2 = fieldnames(app.data_structure.(fnames1{1}));
                    app.Level1DropDown.Items = fnames2;
                    fnames3 = fieldnames(app.data_structure.(fnames1{1}).(fnames2{1}));
                    app.Level2DropDown.Items = fnames3;
%                     fnames4 = fieldnames(app.data_structure.(fnames1{1}).(fnames2{1}).(fnames3{1}));
%                     app.Level3DropDown.Items = fnames4;
                    app.Level3DropDown.Items = {};
                case 'InverseKinematics'
                    fnames1 = fieldnames(app.ik_structure);
                    app.TrialDropDown.Items = fnames1;
                    fnames2 = fieldnames(app.ik_structure.(fnames1{1}));
                    app.Level1DropDown.Items = fnames2;
                    app.Level2DropDown.Items = {};
                    app.Level3DropDown.Items = {};
                case 'InverseDynamics'
                    fnames1 = fieldnames(app.id_structure);
                    app.TrialDropDown.Items = fnames1;
                    fnames2 = fieldnames(app.id_structure.(fnames1{1}));
                    app.Level1DropDown.Items = fnames2;
                    app.Level2DropDown.Items = {};
                    app.Level3DropDown.Items = {};
                case 'Muscle'
                    fnames1 = fieldnames(app.ma_structure);
                    app.TrialDropDown.Items = fnames1;
                    fnames2 = fieldnames(app.ma_structure.(fnames1{1}));
                    app.Level1DropDown.Items = fnames2;
                    fnames3 = fieldnames(app.ma_structure.(fnames1{1}).(fnames2{1}));
                    app.Level2DropDown.Items = fnames3;
                    app.Level3DropDown.Items = {};
            end
            output_variables(app)
        end
        
        function output_variables(app)
            % chose the output structure based on the analysis selected
            switch app.AnalysisDropDown.Value
                case 'Data'
                    output = app.data_structure;
                case 'InverseKinematics'
                    output = app.ik_structure;
                case 'InverseDynamics'
                    output = app.id_structure;
                case 'Muscle'
                    output = app.ma_structure;
            end
            
            % define the output structure based on the number of levels
            if ~isempty(app.Level3DropDown.Value)
                out = output.(app.TrialDropDown.Value).(app.Level1DropDown.Value).(app.Level2DropDown.Value).(app.Level3DropDown.Value);
            elseif ~isempty(app.Level2DropDown.Value)
                out = output.(app.TrialDropDown.Value).(app.Level1DropDown.Value).(app.Level2DropDown.Value);
            else out = output.(app.TrialDropDown.Value).(app.Level1DropDown.Value);
            end
            
            if isstruct(out)
                if isempty(fieldnames(out))
                    app.StringOutputEditField.Value = '';
                    app.ScalarOutputEditField.Value = Inf;
                    cla(app.UIAxes)
                end
            else
                % output the variable depending on type
                if ischar(out)
                    app.StringOutputEditField.Value = out;
                    app.ScalarOutputEditField.Value = Inf;
                    cla(app.UIAxes)
                elseif isscalar(out)
                    app.ScalarOutputEditField.Value = out;
                    app.StringOutputEditField.Value = '';
                    cla(app.UIAxes)
                else
                    plot(app.UIAxes,out)
                    app.StringOutputEditField.Value = '';
                    app.ScalarOutputEditField.Value = Inf;
                end
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ModelFileButton
        function ModelFileButtonPushed(app, event)
            [fname, pathname] = uigetfile({'*.osim','osim file'}, 'Select opensim generic model');
            app.info_structure.GenericModelFile = [pathname fname];
            app.ModelFileEditField.Value = [pathname fname];
        end

        % Button pushed function: StaticFileButton
        function StaticFileButtonPushed(app, event)
            [filein, pathname] = uigetfile({'*.c3d','C3D file'}, 'Select STATIC C3D data file...');
            %data = btk_loadc3d_2021([pathname filein]);
            app.info_structure.staticC3d = [pathname filein];
            app.StaticC3DEditField.Value = [pathname filein];
        end

        % Button pushed function: DynamicFilesButton
        function DynamicFilesButtonPushed(app, event)
            [filesin, pathname] = uigetfile({'*.c3d','C3D file'}, 'Select DYNAMIC C3D data file...','Multiselect','on');
            app.info_structure.dynamic_C3dpath = pathname;
            app.DynamicFolderEditField.Value = pathname;
            
            cd(pathname);
            
            if ~iscell(filesin)
                filesin = {filesin};
            end
            
            app.info_structure.dynamic_C3dfiles = filesin;
            app.DynamicC3DlistListBox.Items = filesin;
            app.DataimportandconvertCheckBox.Value = 1;
        end

        % Button pushed function: RunBatchProcessToolsButton
        function RunBatchProcessToolsButtonPushed(app, event)
            % clear the output drop down menus
            app.AnalysisDropDown.Items = {};
            app.TrialDropDown.Items = {};
            app.Level1DropDown.Items = {};
            app.Level2DropDown.Items = {};
            app.Level3DropDown.Items = {};
            
            % write the important parameters into the info structure 
            % in case we need to load these later on
            app.info_structure.ParticipantCode = app.ParticipantCodeEditField.Value;
            app.info_structure.Mass = app.ParticipantMasskgEditField.Value;
            app.info_structure.MassScaler = app.MassScalerEditField.Value;
            app.info_structure.KinematicsLowPass = app.KinematicsLowPassFreqHzSpinner.Value;
            app.info_structure.ForceLowPass = app.ForceLowPassFreqHzSpinner.Value;
            
            if ~isempty(app.info_structure.dynamic_C3dfiles) && ~isempty(app.info_structure.staticC3d) && ~isempty(app.info_structure.GenericModelFile)
                
                app.AnalysisDropDown.Items = {};
                if app.DataimportandconvertCheckBox.Value
                    run_dataimport(app);
                    D.data_strucutre = app.data_structure;
                    app.AnalysisDropDown.Items = [app.AnalysisDropDown.Items {'Data'}];
                end
                
                if app.ScaleCheckBox.Value
                    run_scale_app(app);
                    D.ik_strucutre = app.ik_structure;                    
                else
                    [fname, pathname] = uigetfile({'*.osim','osim file'}, 'Select scaled model file (OSIM)');
                    app.info_structure.ScaledModel = [pathname fname];
                end
                
                if app.InverseKinematicsCheckBox.Value
                    run_ik_app(app);
                    D.ik_strucutre = app.ik_structure;
                    app.AnalysisDropDown.Items = [app.AnalysisDropDown.Items {'InverseKinematics'}];
                end
                
                if app.InverseDynamicsCheckBox.Value
                    run_id_app(app);
                    D.id_strucutre = app.id_structure;
                    app.AnalysisDropDown.Items = [app.AnalysisDropDown.Items {'InverseDynamics'}];
                end
                
                if app.MuscleAnalysisCheckBox.Value
                    run_ma_app(app);
                    D.ma_strucutre = app.ma_structure;
                    app.AnalysisDropDown.Items = [app.AnalysisDropDown.Items {'Muscle'}];
                end
                D.filelist = app.filelist;   
                app.AnalysisDropDown.Value = app.AnalysisDropDown.Items{1};
                set_analysis_dropdown(app)
                
                save([app.info_structure.dynamic_C3dpath app.info_structure.ParticipantCode '.mat'],'D');
                app.all_data = D;
                
                set_analysis_dropdown(app)
                
            else
            end
            
        end

        % Button pushed function: ScaleFileButton
        function ScaleFileButtonPushed(app, event)
            [fname, pathname] = uigetfile({'*.xml','xml file'}, 'Select generic scale setup file');
            xmlfname = [pathname fname];
            app.ScaleXMLEditField.Value = xmlfname;
            app.ScaleCheckBox.Value = 1;
            app.info_structure.scale.check = 1;
            app.info_structure.scale.XMLfile = xmlfname;
        end

        % Button pushed function: IKFileButton
        function IKFileButtonPushed(app, event)
            [fname, pathname] = uigetfile({'*.xml','xml file'}, 'Select generic IK setup file');
            xmlfname = [pathname fname];
            app.IKXMLEditField.Value = xmlfname;
            app.InverseKinematicsCheckBox.Value = 1;
            app.info_structure.ik.check = 1;
            app.info_structure.ik.XMLfile = xmlfname;
        end

        % Button pushed function: IDLoadsFileButton
        function IDLoadsFileButtonPushed(app, event)
            [fname, pathname] = uigetfile({'*.xml','xml file'}, 'Select generic external loads file');
            xmlfname = [pathname fname];
            app.ExternalLoadsXMLEditField.Value = xmlfname;
            app.InverseDynamicsCheckBox.Value = 1;
            app.info_structure.id.check = 1;
            app.info_structure.id.XMLfile = xmlfname;
        end

        % Menu selected function: LoadSettingsMenu
        function LoadSettingsMenuSelected(app, event)
            % load the settings files from the directory where the app sits
%             a = which('opensim_batch_uq_v0_3.mlapp');
%             [app_path,~,~] = fileparts(a);
            [filename, pathname] = uigetfile('*.mat', 'Load Settings MAT file');
            load([pathname filename],'opensim_batch_uq_settings');
            % load the participant and filter parameters
            app.ParticipantCodeEditField.Value = opensim_batch_uq_settings.ParticipantCode;
            app.ParticipantMasskgEditField.Value = opensim_batch_uq_settings.Mass;
            app.MassScalerEditField.Value = opensim_batch_uq_settings.MassScaler;
            app.KinematicsLowPassFreqHzSpinner.Value = opensim_batch_uq_settings.KinematicsLowPass;
            app.ForceLowPassFreqHzSpinner.Value = opensim_batch_uq_settings.ForceLowPass;
            app.DataimportandconvertCheckBox.Value = opensim_batch_uq_settings.DataCheck;
            
            % load the model and file parameters if they exist
            if isfield(opensim_batch_uq_settings,'GenericModelFile') 
                app.ModelFileEditField.Value = opensim_batch_uq_settings.GenericModelFile; 
                app.info_structure.GenericModelFile = opensim_batch_uq_settings.GenericModelFile; 
            end
            if isfield(opensim_batch_uq_settings,'staticC3d')
                app.StaticC3DEditField.Value = opensim_batch_uq_settings.staticC3d; 
                app.info_structure.staticC3d = opensim_batch_uq_settings.staticC3d;
            end
            if isfield(opensim_batch_uq_settings,'dynamic_C3dfiles')
                app.DynamicC3DlistListBox.Items = opensim_batch_uq_settings.dynamic_C3dfiles; 
                app.info_structure.dynamic_C3dfiles = opensim_batch_uq_settings.dynamic_C3dfiles;
            end
            if isfield(opensim_batch_uq_settings,'dynamic_C3dpath')
                app.DynamicFolderEditField.Value = opensim_batch_uq_settings.dynamic_C3dpath;
                 app.info_structure.dynamic_C3dpath = opensim_batch_uq_settings.dynamic_C3dpath;
            end
            
            % load the setup file parameters
            if isfield(opensim_batch_uq_settings,'custom_import')
                app.CustomimportEditField.Value = opensim_batch_uq_settings.custom_import.m_file;
            end
            if isfield(opensim_batch_uq_settings,'scale')                
                app.ScaleCheckBox.Value = opensim_batch_uq_settings.scale.check;
                app.info_structure.scale.check = opensim_batch_uq_settings.scale.check;
                app.ScaleXMLEditField.Value = opensim_batch_uq_settings.scale.XMLfile;
                app.info_structure.scale.XMLfile = opensim_batch_uq_settings.scale.XMLfile;
            end
            if isfield(opensim_batch_uq_settings,'ik')
                app.InverseKinematicsCheckBox.Value = opensim_batch_uq_settings.ik.check;
                app.info_structure.ik.check = opensim_batch_uq_settings.ik.check;
                app.IKXMLEditField.Value = opensim_batch_uq_settings.ik.XMLfile;
                app.info_structure.ik.XMLfile = opensim_batch_uq_settings.ik.XMLfile;
            end
            if isfield(opensim_batch_uq_settings,'id')
                app.InverseDynamicsCheckBox.Value = opensim_batch_uq_settings.id.check;
                app.info_structure.id.check = opensim_batch_uq_settings.id.check;
                app.ExternalLoadsXMLEditField.Value = opensim_batch_uq_settings.id.XMLfile;
                app.info_structure.id.XMLfile = opensim_batch_uq_settings.id.XMLfile;
            end
            if isfield(opensim_batch_uq_settings,'id')
                app.MuscleAnalysisCheckBox.Value = opensim_batch_uq_settings.ma.check;
                app.info_structure.ma.check = opensim_batch_uq_settings.ma.check;
            end
            
        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)
            % write the participant and filtering information to the
            % info_structure if they aren't there already
            app.info_structure.ParticipantCode = app.ParticipantCodeEditField.Value;
            app.info_structure.Mass = app.ParticipantMasskgEditField.Value;
            app.info_structure.MassScaler = app.MassScalerEditField.Value;
            app.info_structure.KinematicsLowPass = app.KinematicsLowPassFreqHzSpinner.Value;
            app.info_structure.ForceLowPass = app.ForceLowPassFreqHzSpinner.Value;
            app.info_structure.DataCheck = app.DataimportandconvertCheckBox.Value;
            
            % determine other values from input boxes and save these into
            % the info_structure
            app.info_structure.GenericModelFile = app.ModelFileEditField.Value;
            app.info_structure.staticC3d = app.StaticC3DEditField.Value;
            app.info_structure.dynamic_C3dfiles = app.DynamicC3DlistListBox.Items;
            app.info_structure.dynamic_C3dpath = app.DynamicFolderEditField.Value;
            
            app.info_structure.custom_import.m_file = app.CustomimportEditField.Value;
            app.info_structure.scale.check = app.ScaleCheckBox.Value;
            app.info_structure.scale.XMLfile = app.ScaleXMLEditField.Value;
            app.info_structure.ik.check = app.InverseKinematicsCheckBox.Value;
            app.info_structure.ik.XMLfile = app.IKXMLEditField.Value;
            app.info_structure.id.check = app.InverseDynamicsCheckBox.Value;
            app.info_structure.id.XMLfile = app.ExternalLoadsXMLEditField.Value;
            app.info_structure.ma.check = app.MuscleAnalysisCheckBox.Value;

            % find the folder where the app sits and save the settings file
            % in the same folder - settings info is just the info_structure
            % structure
            a = which('opensim_batch_uq_v0_3.mlapp');
            [app_path,~,~] = fileparts(a);            
            [filename, pathname] = uiputfile('*.mat', 'Save Settings MAT file');
            opensim_batch_uq_settings = app.info_structure;
            save([pathname filename],'opensim_batch_uq_settings')  
            save(app_path,'opensim_batch_uq_settings') 
        end

        % Menu selected function: ClearAllMenu
        function ClearAllMenuSelected(app, event)
            app.info_structure = [];
            app.data_structure = []; % the structure containing the data for each file
            app.scale_structure = []; % the structure containing key scaling outputs
            app.ik_structure = []; % the structure containing the IK outputs
            app.id_structure = []; % the structure containing the ID outputs
            app.ma_structure = [];% the structure containing the MA outputs
            app.all_data = [];
            app.out_text = []; % the text output for processing
            app.filelist = [];
            app.StaticC3DEditField.Value = '';
            app.DynamicC3DlistListBox.Items = {''};
            app.DynamicFolderEditField.Value = '';
        end

        % Value changed function: AnalysisDropDown
        function AnalysisDropDownValueChanged(app, event)
            %value = app.AnalysisDropDown.Value;            
            set_analysis_dropdown(app)
        end

        % Value changed function: TrialDropDown
        function TrialDropDownValueChanged(app, event)
            %value = app.TrialDropDown.Value;
            output_variables(app)
        end

        % Value changed function: Level1DropDown
        function Level1DropDownValueChanged(app, event)
            %value = app.Level1DropDown.Value;
            if strcmp(app.AnalysisDropDown.Value, 'Data')
                if isstruct(app.data_structure.(app.TrialDropDown.Value).(app.Level1DropDown.Value))
                    fnames1 = fieldnames(app.data_structure.(app.TrialDropDown.Value).(app.Level1DropDown.Value));
                    app.Level2DropDown.Items = fnames1;
                    app.Level2DropDown.Value = fnames1{1};
                    if isstruct(app.data_structure.(app.TrialDropDown.Value).(app.Level1DropDown.Value).(app.Level2DropDown.Value))
                        fnames2 = fieldnames(app.data_structure.(app.TrialDropDown.Value).(app.Level1DropDown.Value).(fnames1{1}));
                        app.Level3DropDown.Items = fnames2;
                        if ~isempty(fnames2)
                            app.Level3DropDown.Value = fnames2{1};
                        else app.Level3DropDown.Value = {};
                        end
                    else
                        app.Level3DropDown.Items = {};
                    end
                else 
                    app.Level2DropDown.Items = {};
                    app.Level3DropDown.Items = {};
                end
            end
            output_variables(app)
        end

        % Value changed function: Level2DropDown
        function Level2DropDownValueChanged(app, event)
            %value = app.Level2DropDown.Value;
            if strcmp(app.AnalysisDropDown.Value, 'Data')
                if isstruct(app.data_structure.(app.TrialDropDown.Value).(app.Level1DropDown.Value).(app.Level2DropDown.Value))
                    fnames = fieldnames(app.data_structure.(app.TrialDropDown.Value).(app.Level1DropDown.Value).(app.Level2DropDown.Value));
                    app.Level3DropDown.Items = fnames;
                else app.Level3DropDown.Items = {};
                end
            end
            if strcmp(app.AnalysisDropDown.Value, 'Muscle')
                if isstruct(app.ma_structure.(app.TrialDropDown.Value).(app.Level1DropDown.Value).(app.Level2DropDown.Value))
                    fnames = fieldnames(app.data_structure.(app.TrialDropDown.Value).(app.Level1DropDown.Value).(app.Level2DropDown.Value));
                    app.Level3DropDown.Items = fnames;
                else app.Level3DropDown.Items = {};
                end
            end
            output_variables(app)
        end

        % Value changed function: Level3DropDown
        function Level3DropDownValueChanged(app, event)
            %value = app.Level3DropDown.Value;
            output_variables(app)
        end

        % Value changed function: HoldonCheckBox
        function HoldonCheckBoxValueChanged(app, event)
            value = app.HoldonCheckBox.Value;
            if value 
                hold(app.UIAxes,'on')
            else
                hold(app.UIAxes,'off')
            end
        end

        % Button pushed function: CustomMFileButton
        function CustomMFileButtonPushed(app, event)
            [fname, pathname] = uigetfile({'*.m','m-file'}, 'Select a custom m-file to process data for analysis');
            mfname = [pathname fname];
            app.CustomimportEditField.Value = mfname;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.LoadSettingsMenu = uimenu(app.SettingsMenu);
            app.LoadSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsMenuSelected, true);
            app.LoadSettingsMenu.Text = 'Load Settings';
            app.SaveSettingsMenu = uimenu(app.SettingsMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.ClearAllMenu = uimenu(app.SettingsMenu);
            app.ClearAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllMenuSelected, true);
            app.ClearAllMenu.Text = 'Clear All';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph Output')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ParticipantandFilteringParametersPanel = uipanel(app.UIFigure);
            app.ParticipantandFilteringParametersPanel.Title = 'Participant and Filtering Parameters';
            app.KinematicsLowPassFreqHzSpinnerLabel = uilabel(app.ParticipantandFilteringParametersPanel);
            app.KinematicsLowPassFreqHzSpinnerLabel.Text = 'Kinematics Low Pass Freq (Hz)';
            app.KinematicsLowPassFreqHzSpinner = uispinner(app.ParticipantandFilteringParametersPanel);
            app.KinematicsLowPassFreqHzSpinner.Limits = [0 100];
            app.KinematicsLowPassFreqHzSpinner.Value = 15;
            app.ForceLowPassFreqHzSpinnerLabel = uilabel(app.ParticipantandFilteringParametersPanel);
            app.ForceLowPassFreqHzSpinnerLabel.Text = 'Force Low Pass Freq (Hz)';
            app.ForceLowPassFreqHzSpinner = uispinner(app.ParticipantandFilteringParametersPanel);
            app.ForceLowPassFreqHzSpinner.Limits = [0 100];
            app.ForceLowPassFreqHzSpinner.Value = 15;
            app.ParticipantCodeEditFieldLabel = uilabel(app.ParticipantandFilteringParametersPanel);
            app.ParticipantCodeEditFieldLabel.Text = 'Participant Code';
            app.ParticipantCodeEditField = uieditfield(app.ParticipantandFilteringParametersPanel, 'text');
            app.ParticipantCodeEditField.Value = 'Code1';
            app.ParticipantMasskgEditFieldLabel = uilabel(app.ParticipantandFilteringParametersPanel);
            app.ParticipantMasskgEditFieldLabel.Text = 'Participant Mass (kg)';
            app.ParticipantMasskgEditField = uieditfield(app.ParticipantandFilteringParametersPanel, 'numeric');
            app.ParticipantMasskgEditField.Limits = [0 200];
            app.ParticipantMasskgEditField.Value = 75;
            app.MassScaler01EditFieldLabel = uilabel(app.ParticipantandFilteringParametersPanel);
            app.MassScaler01EditFieldLabel.Text = 'Mass Scaler (0-1)';
            app.MassScalerEditField = uieditfield(app.ParticipantandFilteringParametersPanel, 'numeric');
            app.MassScalerEditField.Limits = [0 1];
            app.MassScalerEditField.Value = 1;
            app.ModelFileButton = uibutton(app.UIFigure, 'push');
            app.ModelFileButton.ButtonPushedFcn = createCallbackFcn(app, @ModelFileButtonPushed, true);
            app.ModelFileButton.Text = '/';
            app.StaticFileButton = uibutton(app.UIFigure, 'push');
            app.StaticFileButton.ButtonPushedFcn = createCallbackFcn(app, @StaticFileButtonPushed, true);
            app.StaticFileButton.Text = '/';
            app.DynamicFilesButton = uibutton(app.UIFigure, 'push');
            app.DynamicFilesButton.ButtonPushedFcn = createCallbackFcn(app, @DynamicFilesButtonPushed, true);
            app.DynamicFilesButton.Text = {'/'; ''};
            app.DynamicC3DlistListBoxLabel = uilabel(app.UIFigure);
            app.DynamicC3DlistListBoxLabel.Text = {'Dynamic'; 'C3D list'};
            app.DynamicC3DlistListBox = uilistbox(app.UIFigure);
            app.StaticC3DEditFieldLabel = uilabel(app.UIFigure);
            app.StaticC3DEditFieldLabel.Text = 'Static C3D';
            app.StaticC3DEditField = uieditfield(app.UIFigure, 'text');
            app.ModelFileEditFieldLabel = uilabel(app.UIFigure);
            app.ModelFileEditFieldLabel.Text = 'Model File';
            app.ModelFileEditField = uieditfield(app.UIFigure, 'text');
            app.DynamicFolderEditFieldLabel = uilabel(app.UIFigure);
            app.DynamicFolderEditFieldLabel.Text = {'Dynamic'; 'Folder'};
            app.DynamicFolderEditField = uieditfield(app.UIFigure, 'text');
            app.ScaleCheckBox = uicheckbox(app.UIFigure);
            app.ScaleCheckBox.Text = 'Scale';
            app.InverseKinematicsCheckBox = uicheckbox(app.UIFigure);
            app.InverseKinematicsCheckBox.Text = 'Inverse Kinematics';
            app.InverseDynamicsCheckBox = uicheckbox(app.UIFigure);
            app.InverseDynamicsCheckBox.Text = 'Inverse Dynamics';
            app.MuscleAnalysisCheckBox = uicheckbox(app.UIFigure);
            app.MuscleAnalysisCheckBox.Text = 'Muscle Analysis';
            app.ScaleFileButton = uibutton(app.UIFigure, 'push');
            app.ScaleFileButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleFileButtonPushed, true);
            app.ScaleFileButton.Text = {'/'; ''};
            app.IKFileButton = uibutton(app.UIFigure, 'push');
            app.IKFileButton.ButtonPushedFcn = createCallbackFcn(app, @IKFileButtonPushed, true);
            app.IKFileButton.Text = {'/'; ''};
            app.IDLoadsFileButton = uibutton(app.UIFigure, 'push');
            app.IDLoadsFileButton.ButtonPushedFcn = createCallbackFcn(app, @IDLoadsFileButtonPushed, true);
            app.IDLoadsFileButton.Text = {'/'; ''};
            app.DataimportandconvertCheckBox = uicheckbox(app.UIFigure);
            app.DataimportandconvertCheckBox.Text = 'Data import and convert';
            app.RunBatchProcessToolsButton = uibutton(app.UIFigure, 'push');
            app.RunBatchProcessToolsButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchProcessToolsButtonPushed, true);
            app.RunBatchProcessToolsButton.Text = 'Run Batch Process Tools';
            app.ScaleXMLEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleXMLEditFieldLabel.Text = 'Scale XML';
            app.ScaleXMLEditField = uieditfield(app.UIFigure, 'text');
            app.IKXMLEditFieldLabel = uilabel(app.UIFigure);
            app.IKXMLEditFieldLabel.Text = 'IK XML';
            app.IKXMLEditField = uieditfield(app.UIFigure, 'text');
            app.ExternalLoadsXMLEditFieldLabel = uilabel(app.UIFigure);
            app.ExternalLoadsXMLEditFieldLabel.Text = 'External Loads XML';
            app.ExternalLoadsXMLEditField = uieditfield(app.UIFigure, 'text');
            app.ProcessingProgressTextAreaLabel = uilabel(app.UIFigure);
            app.ProcessingProgressTextAreaLabel.Text = {'Processing'; 'Progress'};
            app.ProcessingProgressTextArea = uitextarea(app.UIFigure);
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldonCheckBoxValueChanged, true);
            app.HoldonCheckBox.Text = 'Hold on';
            app.AnalysisDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.UIFigure);
            app.AnalysisDropDown.Items = {};
            app.AnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisDropDownValueChanged, true);
            app.AnalysisDropDown.Value = {};
            app.TrialDropDownLabel = uilabel(app.UIFigure);
            app.TrialDropDownLabel.Text = 'Trial';
            app.TrialDropDown = uidropdown(app.UIFigure);
            app.TrialDropDown.Items = {};
            app.TrialDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialDropDownValueChanged, true);
            app.TrialDropDown.Value = {};
            app.Level1DropDownLabel = uilabel(app.UIFigure);
            app.Level1DropDownLabel.Text = 'Level 1';
            app.Level1DropDown = uidropdown(app.UIFigure);
            app.Level1DropDown.Items = {};
            app.Level1DropDown.ValueChangedFcn = createCallbackFcn(app, @Level1DropDownValueChanged, true);
            app.Level1DropDown.Value = {};
            app.Level2DropDownLabel = uilabel(app.UIFigure);
            app.Level2DropDownLabel.Text = 'Level 2';
            app.Level2DropDown = uidropdown(app.UIFigure);
            app.Level2DropDown.Items = {};
            app.Level2DropDown.ValueChangedFcn = createCallbackFcn(app, @Level2DropDownValueChanged, true);
            app.Level2DropDown.Value = {};
            app.ScalarOutputEditFieldLabel = uilabel(app.UIFigure);
            app.ScalarOutputEditFieldLabel.Text = 'Scalar Output';
            app.ScalarOutputEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScalarOutputEditField.Editable = 'off';
            app.StringOutputEditFieldLabel = uilabel(app.UIFigure);
            app.StringOutputEditFieldLabel.Text = 'String Output';
            app.StringOutputEditField = uieditfield(app.UIFigure, 'text');
            app.StringOutputEditField.Editable = 'off';
            app.Level3DropDownLabel = uilabel(app.UIFigure);
            app.Level3DropDownLabel.Text = 'Level 3';
            app.Level3DropDown = uidropdown(app.UIFigure);
            app.Level3DropDown.Items = {};
            app.Level3DropDown.ValueChangedFcn = createCallbackFcn(app, @Level3DropDownValueChanged, true);
            app.Level3DropDown.Value = {};
            app.BodyAnalysisCheckBox = uicheckbox(app.UIFigure);
            app.BodyAnalysisCheckBox.Text = 'Body Analysis';
            app.CustomimportEditFieldLabel = uilabel(app.UIFigure);
            app.CustomimportEditFieldLabel.Text = 'Custom import';
            app.CustomimportEditField = uieditfield(app.UIFigure, 'text');
            app.CustomMFileButton = uibutton(app.UIFigure, 'push');
            app.CustomMFileButton.ButtonPushedFcn = createCallbackFcn(app, @CustomMFileButtonPushed, true);
            app.CustomMFileButton.Text = '/';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opensim_batch_uq_v0_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end